classdef ImageCropper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        CropAllImagesButton  matlab.ui.control.Button
        P1EditFieldLabel     matlab.ui.control.Label
        P1EditField          matlab.ui.control.NumericEditField
        P2EditFieldLabel     matlab.ui.control.Label
        P2EditField          matlab.ui.control.NumericEditField
        P3EditFieldLabel     matlab.ui.control.Label
        P3EditField          matlab.ui.control.NumericEditField
        P4EditFieldLabel     matlab.ui.control.Label
        P4EditField          matlab.ui.control.NumericEditField
        UITable              matlab.ui.control.Table
        SelectCropBoxButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        displayedImageIndex; %double
        P1;
        P2;
        P3;
        P4;

    end
    
    methods (Access = private)
        
        function updateDisplayedImages(app)
            
            app.Image.ImageSource = imread(app.UITable.Data(app.displayedImageIndex,1));

        end
        
        function loadimage(app)  
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
               filename = [path file];
            else
                return;
            end
            
            image = imread(filename);
            [height, width, dim] = size(image);
            app.UITable.Data = [app.UITable.Data ; [filename,strcat(num2str(height),"x", num2str(width), "x",num2str(dim))]];
        end
        
        function cropAllImages(app)
             selectedDirectory = uigetdir();
             % Make sure user didn't cancel uigetdir dialog
            if (selectedDirectory == 0)
                return;
            end
            
            for i = 1:size(app.UITable.Data,1)
                image = imread(app.UITable.Data(i,1));
                image = imcrop(image,[app.P1 app.P2 app.P3 app.P4]);
                imwrite(image,strcat(selectedDirectory,"\img",num2str(i),"_cropped.png"));
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
                loadimage(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.displayedImageIndex = indices(1);
            updateDisplayedImages(app);
        end

        % Button pushed function: CropAllImagesButton
        function CropAllImagesButtonPushed(app, event)
            cropAllImages(app);
        end

        % Button pushed function: SelectCropBoxButton
        function SelectCropBoxButtonPushed(app, event)
            image = imread(app.UITable.Data(app.displayedImageIndex,1));
            imshow(image);
            rect = drawrectangle();
            app.P1 = rect.Position(1);
            app.P2 = rect.Position(2);
            app.P3 = rect.Position(3);
            app.P4 = rect.Position(4);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CropAllImagesButton = uibutton(app.UIFigure, 'push');
            app.CropAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CropAllImagesButtonPushed, true);
            app.CropAllImagesButton.Text = 'Crop All Images';
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.UIFigure, 'numeric');
            app.P2EditFieldLabel = uilabel(app.UIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.UIFigure, 'numeric');
            app.P3EditFieldLabel = uilabel(app.UIFigure);
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.UIFigure, 'numeric');
            app.P4EditFieldLabel = uilabel(app.UIFigure);
            app.P4EditFieldLabel.Text = 'P4';
            app.P4EditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Filepath'; 'Dimensions'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true];
            app.UITable.ColumnEditable = [false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectCropBoxButton = uibutton(app.UIFigure, 'push');
            app.SelectCropBoxButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCropBoxButtonPushed, true);
            app.SelectCropBoxButton.Text = 'Select Crop Box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageCropper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end