classdef MaskGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        EdgeDetectionPanel       matlab.ui.container.Panel
        ConnectedComponentLabelingPanel  matlab.ui.container.Panel
        CCLButton                matlab.ui.control.Button
        MorphologyPanel          matlab.ui.container.Panel
        MorphButton              matlab.ui.control.Button
        RegionGrowingPanel       matlab.ui.container.Panel
        ToleranceSlider          matlab.ui.control.Slider
        ToleranceSliderLabel     matlab.ui.control.Label
        GrowNewRegionButton      matlab.ui.control.Button
        OtsuThresholdPanel       matlab.ui.container.Panel
        ManualThresholdPanel     matlab.ui.container.Panel
        FlipMaskButton           matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        ManualThresholdLabel     matlab.ui.control.Label
        Step3GenerateMasksLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ImageCounterLabel        matlab.ui.control.Label
        NextButton               matlab.ui.control.Button
        PrevButton               matlab.ui.control.Button
        NextStepButton           matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        SaveMaskButton           matlab.ui.control.Button
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        OriginalImageLabel       matlab.ui.control.Label
        MaskedImageLabel         matlab.ui.control.Label
        CompositeImageLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        name;
        coordinates;
        band;
        location; %char. The location of the sattelite images
        pixelSize; %char. The pixel size of the satellite images
        method; %char. The method of segmentation used
        images; % cell.
        imagesCount; % uint8
        displayedImageIndex = 1; % double. The index of the currently displayed image
        flippedMask = 0; % double. A boolean for flipping the mask colors.
        masks; %cell. A storage matrix of all masks of all images
        
    end
    
    methods (Access = private)
        
        function manualThresholding(app,imageIndex,threshold)
            
            temp_OriginalImage = app.images{1,imageIndex}; % load the original image from the data table
            temp_maskedImage = rgb2gray(temp_OriginalImage) >= threshold; % compute a binary mask (>=)
            
            if app.flippedMask == 1
                temp_maskedImage = ~temp_maskedImage;
            end
            
            app.masks{imageIndex} = temp_maskedImage; % we save the mask on the masks cell
        end
        
        function otsu(app,imageIndex)
            
            temp_OriginalImage = app.images{1,imageIndex}; % load the original image from the data table
            temp_MaskedImage = rgb2gray(temp_OriginalImage); % compute a binary mask (>=)
            
            top = 256;
            maximumInterClassVariance = 0.0;
            
            for i = 1:top
                foreGroundPixels = temp_MaskedImage >= i;
                backGroundPixels = ~foreGroundPixels;
                
                numberOfPixelsInForeground = sum(foreGroundPixels(:) == 1);
                meanIntensityOfForeground = mean(temp_MaskedImage(temp_MaskedImage >= i));
                
                numberOfPixelsInBackground = sum(backGroundPixels(:) == 1);
                meanIntensityOfBackground = mean(temp_MaskedImage(temp_MaskedImage < i));
                
                interClassVariance = numberOfPixelsInBackground * numberOfPixelsInForeground * (meanIntensityOfBackground - meanIntensityOfForeground)^2;
                
                if (interClassVariance >= maximumInterClassVariance)
                    level = i;
                    maximumInterClassVariance = interClassVariance;
                end
            end
            
            temp_MaskedImage = rgb2gray(temp_OriginalImage) <= level; % compute a binary mask
            app.masks{imageIndex} = temp_MaskedImage; % we save the mask on the masks cell
            
        end

        function connectedComponentLabeling(app)
            
        end
        
        function edgeDetection(app)
            
        end

%Written by Maximilian Payer, 12023998
%A function the procedurally creats a mask based on a starting position of
%choice and the color values of adjacent pixels
function regionGrowing(app,imageIndex,similarity)
    
    image = app.images{1,imageIndex}; % load the original image from the data table
    mask = app.masks{1,imageIndex}; % compute a binary mask (>=)
    
    if(mask == 0)  % if mask is the scalar value 0, it creates an empty mask
        mask = im2gray(zeros(size(image)));
    end
    
    imshow(im2uint8(mask) + image); % show the combined image

    % try seed = ginput(1) % get the location of one mouse click
    try seed = ginput(1); % wait for one mouse click
    catch 
    end

    colorValue = image(uint16(seed(1, 2)), uint16(seed(1, 1)), :);

    result = or(mask, recursiveSeedGrowing(app, image, similarity, seed, mask, colorValue)); 
    %add newly created mask to the existing mask

    imshow(im2uint8(cat(3,result,result,result)) + image); % show the combined image 
    %(called twice, so that the changes are visible immediately)
    
    
    temp_MaskedImage = result;
    
    app.masks{imageIndex} = temp_MaskedImage; % save the mask so we can keep adding information to it
    
end

%Written by Maximilian Payer, 12023998
%A recursive function that grows a region, given a starting seed
function [temp_maskedImage] = recursiveSeedGrowing(app, image, similarity, seed, mask, colorValue)

    seedY = uint16(seed(1, 1));  %acquire the positions of the current Pixel
    seedX = uint16(seed(1, 2));  %acquire the positions of the current Pixel
    %it is to be noted that the horizontal values of the image
    %matrix are actually the second dimension, while the vertical
    %values are the first dimension. Hence, seedX stands for vertical
    %values, and vice versa

    temp_maskedImage = mask; 

    if(abs(sum(image(seedX, seedY, :) - colorValue)) > similarity*2)
        return;
    end

    temp_maskedImage = regionGrowingChunkHeuristic(app, image, similarity, seed, temp_maskedImage, 1, colorValue);
    temp_maskedImage(seedX, seedY) = 1; %add the current pixel to the mask


    %the following code block is only executed if the current pixel
    %is not on the very edge of the image (since checking the
    %neighbors would go out of bounds)
    if(seedX > 1 && seedY > 1 && seedX < size(image, 1) && seedY < size(image, 2))
        if(temp_maskedImage(seedX+1, seedY) == 0 && ... %check the pixel above
                sum(image(seedX+1, seedY, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX+1, seedY, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 2) = newSeed(1, 2) +1; 
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue); %recursive call on shifted seed
        end
        if(temp_maskedImage(seedX-1, seedY, :) == 0 && ... %check the pixel below
                sum(image(seedX-1, seedY, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX-1, seedY, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 2) = newSeed(1, 2) -1;
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue); %recursive call on shifted seed
        end
        if(temp_maskedImage(seedX, seedY+1, :) == 0 && ... %check the pixel to the right
                sum(image(seedX, seedY+1, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX, seedY+1, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 1) = newSeed(1, 1) +1;
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue);  %recursive call on shifted seed
        end
        if(temp_maskedImage(seedX, seedY-1, :) == 0 && ... %check the pixel to the left
                sum(image(seedX, seedY-1, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX, seedY-1, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 1) = newSeed(1, 1) -1;
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue); %recursive call on shifted seed 
        end
        

    end

end

%Written by Maximilian Payer, 12023998
%A recursive auxiliary function that aids the region growing function and
%increases its performance
function [mask] = regionGrowingChunkHeuristic(app, image, similarity, seed, mask, depth, colorValue)
    
    seedY = uint16(seed(1, 1));  %acquire the position of the current Pixel
    seedX = uint16(seed(1, 2));  %acquire the position of the current Pixel

    if(mask(seedX, seedY) == 1)
        return
    end

    maxdepth = floor(min(min(min(size(image, 1) - seedX, size(image, 2) - seedY), seedX), seedY)/ 2) -2;
    %The maximum size that the heuristic can use for the given seed (based
    %on the image borders)


    if(depth > maxdepth)
        return;
    end

    newSeed = seed;

   %A rough heuristic check whether the square region of the given size (depth)
   %can be added without obstruction. If not, then a thresholding check
   %adds the appropriate pixels in a 11x11 region
   if((abs(sum(image(seedX + depth*2, seedY, :)  - colorValue)) <= similarity*2 && ...
   abs(sum(image(seedX, seedY + depth*2, :)  - colorValue)) <= similarity*2  && ...
   abs(sum(image(seedX - depth*2, seedY, :)  - colorValue)) <= similarity*2  && ...
   abs(sum(image(seedX, seedY - depth*2, :)  - colorValue)) <= similarity*2  ))

      mask = regionGrowingChunkHeuristic(app, image, similarity, seed, mask, depth + 1, colorValue);

   elseif (depth <= 3 && maxdepth > 5)
        mask(seedX- 5*2:seedX+5*2, seedY-5*2:seedY+5*2) = ...
        sum(image(seedX-5*2:seedX+5*2, seedY-5*2:seedY+5*2) - colorValue, 3) <= similarity*2;

           for i = 1:5*2
               newSeed(1,2) = seedX - 5*2 + i*2;
               newSeed(1,1) = seedY - 5*2 - 1;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
               
               newSeed(1,2) = seedX - 5*2 - 1;
               newSeed(1,1) = seedY - 5*2  + i*2;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
    
               newSeed(1,2) = seedX + 5*2 - i*2;
               newSeed(1,1) = seedY + 5*2 + 1;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
    
               newSeed(1,2) = seedX + 5*2 + 1;
               newSeed(1,1) = seedY + 5*2 - i*2;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
           end
   
           return;
       else 
           return;
   end


   %A precise heuristic check whether the square region of the given size (depth)
   %can be added without obstruction

   for i = 1:depth
       if(abs(sum(image(seedX - depth*2 + i*4, seedY - depth*2, :)  - colorValue)) <= similarity*2 && ...
        abs(sum(image(seedX - depth*2, seedY - depth*2  + i*4, :)  - colorValue)) <= similarity*2  && ...
        abs(sum(image(seedX + depth*2 - i*4, seedY + depth*2, :)  - colorValue)) <= similarity*2  && ...
        abs(sum(image(seedX + depth*2, seedY + depth*2 - i*4, :)  - colorValue)) <= similarity*2  )
        else
           return;
        end
   end

   %functions for testing purposes
   %pause(0.2);
   %mask(1:size(image, 1), 1:size(image, 2)) = 1;
   %imshow(im2uint8(mask) + image);
    
   %Adds the square region to the mask
   mask(seedX-depth*2:seedX+depth*2, seedY-depth*2:seedY+depth*2) = 1;


   %Spreads the region growing algorithm to adjacent pixels of the square region
   for i = 1:depth*2
       newSeed(1,2) = seedX - depth*2 + i*2;
       newSeed(1,1) = seedY - depth*2 - 1;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end
       
       newSeed(1,2) = seedX - depth*2 - 1;
       newSeed(1,1) = seedY - depth*2 + i*2;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end

       newSeed(1,2) = seedX + depth*2 - i*2;
       newSeed(1,1) = seedY + depth*2 + 1;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end

       newSeed(1,2) = seedX + depth*2 + 1;
       newSeed(1,1) = seedY + depth*2 - i*2;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end

   end


end
        
        function morphologicalOperation(app)
            
        end
        
        function generateDefaultMasks(app)
            % generate a default mask for each image
            
            if app.method == "Manual Thresholding"
                for (i = 1:app.imagesCount)
                    manualThresholding(app,i,app.Slider.Value);
                end
            elseif app.method == "Otsu's Thresholding" % check if we are on the otsu's thresholding mode
                for (i = 1:app.imagesCount)
                    otsu(app,i);
                end
            elseif app.method == "Region Growing"
                for (i = 1:app.imagesCount)
                    temp_OriginalImage = app.images{1,i}; % load the original image from the data table
                    temp_maskedImage = rgb2gray(temp_OriginalImage); % compute a binary mask (>=)
                    temp_maskedImage(:) = 0; % set the masking start point to a black image of the same size as the orignal image
                    app.masks{i} = temp_maskedImage; % we save the mask on the masks cell
                end
            end
            
        end
        
        function updateDisplayedImages(app) % updates the displayed images
            
            originalImage = app.images{1,app.displayedImageIndex}; % load the original image from the data table
            maskedImage = app.masks{app.displayedImageIndex}; % Load a mask from the masks cell if it already exists
            combinedImage = im2uint8(cat(3,maskedImage,maskedImage,maskedImage)) + originalImage; % create a composite image of the original image plus the image's mask
            
            app.ImageCounterLabel.Text = strcat("Image ", num2str(app.displayedImageIndex), " of ", num2str(app.imagesCount)); % set the displayed image text
            app.Image.ImageSource = originalImage; % set the imagesource1 to the original image
            app.Image2.ImageSource = im2uint8(cat(3,maskedImage,maskedImage,maskedImage)); % set the imagesource2 to the masked image (the grayscale mask has to be temporarily converted to a RGB image in order to be displayed in the image component)
            app.Image3.ImageSource = combinedImage; % set the imagesource3 to the combined image
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, location, coordinates, pixelSize, band, method, imageData, images, imagesCount)
            app.name = name;
            app.location = location; % store the location value
            app.coordinates = coordinates;
            app.pixelSize = pixelSize; % store the pixel size value
            app.band = band;
            app.method = method; % store the method value
            app.images = images;
            app.imagesCount = imagesCount;
            app.UITable.Data = imageData; % copy the imagedata into a new table
            
            if (strcmp(method,"Manual Thresholding")) % Turn on the UI components exclusive to the Manual Thresholding method
                app.ManualThresholdPanel.Visible = true;
            elseif (strcmp(method,"Otsu's Thresholding")) % Turn on the UI components exclusive to the Otsu's Thresholding method
                app.OtsuThresholdPanel.Visible = true;
            elseif (strcmp(method,"Region Growing")) % Turn on the UI components exclusive to the Region Growing method
                app.RegionGrowingPanel.Visible = true;
            elseif (strcmp(method,"Edge Detection")) % Turn on the UI components exclusive to the Region Growing method
                app.RegionGrowingPanel.Visible = true;
            end
            app.masks = cell(1,imagesCount); % create a new storage matrix for all masks of all pictures, this is initially empty.
            
            generateDefaultMasks(app);
            updateDisplayedImages(app); % update the displayed images
            
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            selectedDirectory = uigetdir(); % get a directory from user where they want to store the mask
            if (selectedDirectory == 0) % Make sure user didn't cancel uigetdir dialog
                return;
            end
            
            originalImage = app.images{1,app.displayedImageIndex}; % load the original image from the data table
            maskedImage = app.masks{app.displayedImageIndex}; % Load a mask from the masks cell if it already exists
            combinedImage = im2uint8(cat(3,maskedImage,maskedImage,maskedImage)) + originalImage;
   
            timestamp = num2str(posixtime(datetime)); 
            
            imwrite(maskedImage,strcat(selectedDirectory,"\",timestamp,"_",num2str(app.displayedImageIndex),"_mask.png")); % store an image of the mask
            imwrite(combinedImage,strcat(selectedDirectory,"\",timestamp,"_",num2str(app.displayedImageIndex),"_mask_combined.png")); % store an image of the composite
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.displayedImageIndex < size(app.UITable.Data,1) % if there is a next row in the table
                app.displayedImageIndex = app.displayedImageIndex + 1; % increase the index of the displayed row
                updateDisplayedImages(app); % update the displayed images
            end

        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if app.displayedImageIndex > 1 % if there is a previous row in the table
                app.displayedImageIndex = app.displayedImageIndex - 1; % decrease the index of the displayed row
                updateDisplayedImages(app); % update the displayed images
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            manualThresholding(app,app.displayedImageIndex,app.Slider.Value);
            updateDisplayedImages(app); % update the displyed images (with a new manual thresholding slider value)
        end

        % Button pushed function: FlipMaskButton
        function FlipMaskButtonPushed(app, event)
            if app.flippedMask == 0 % bitflip the flipped mask boolean
                app.flippedMask = 1;
            else
                app.flippedMask = 0;
            end
            
            manualThresholding(app,app.displayedImageIndex,app.Slider.Value);
            updateDisplayedImages(app); % update the displayed images

        end

        % Button pushed function: GrowNewRegionButton
        function GrowNewRegionButtonPushed(app, event)
            % Do region growing
           
           regionGrowing(app,app.displayedImageIndex,app.ToleranceSlider.Value);
           app.updateDisplayedImages(); % update the displayed images
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            DataPlotting(app.location,app.pixelSize,app.method,app.UITable.Data,app.images,app.imagesCount,app.masks);
            app.delete;
        end

        % Button pushed function: CCLButton
        function CCLButtonPushed(app, event)
            
        end

        % Button pushed function: MorphButton
        function MorphButtonPushed(app, event)
            maskedImage = app.masks{app.displayedImageIndex}; % Load a mask from the masks cell if it already exists            
            %do morphology
            app.masks{app.displayedImageIndex} = maskedImage;
            updateDisplayedImages(app); % update the displayed images
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.CompositeImageLabel = uilabel(app.UIFigure);
            app.CompositeImageLabel.Text = 'Composite Image';
            app.MaskedImageLabel = uilabel(app.UIFigure);
            app.MaskedImageLabel.Text = 'Masked Image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.Image3 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.SaveMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Year'; 'Size'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false true false false];
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = '<- Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next ->';
            app.ImageCounterLabel = uilabel(app.UIFigure);
            app.ImageCounterLabel.Text = 'Image 0 of 0';
            app.Image = uiimage(app.UIFigure);
            app.Step3GenerateMasksLabel = uilabel(app.UIFigure);
            app.Step3GenerateMasksLabel.Text = 'Step 3: Generate Masks';
            app.ManualThresholdPanel = uipanel(app.UIFigure);
            app.ManualThresholdPanel.Visible = 'off';
            app.ManualThresholdLabel = uilabel(app.ManualThresholdPanel);
            app.ManualThresholdLabel.Text = 'Manual Threshold';
            app.Slider = uislider(app.ManualThresholdPanel);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.FlipMaskButton = uibutton(app.ManualThresholdPanel, 'push');
            app.FlipMaskButton.ButtonPushedFcn = createCallbackFcn(app, @FlipMaskButtonPushed, true);
            app.FlipMaskButton.Text = 'Flip Mask';
            app.OtsuThresholdPanel = uipanel(app.UIFigure);
            app.OtsuThresholdPanel.Visible = 'off';
            app.RegionGrowingPanel = uipanel(app.UIFigure);
            app.RegionGrowingPanel.Visible = 'off';
            app.GrowNewRegionButton = uibutton(app.RegionGrowingPanel, 'push');
            app.GrowNewRegionButton.ButtonPushedFcn = createCallbackFcn(app, @GrowNewRegionButtonPushed, true);
            app.GrowNewRegionButton.Text = 'Grow New Region';
            app.ToleranceSliderLabel = uilabel(app.RegionGrowingPanel);
            app.ToleranceSliderLabel.Text = 'Tolerance';
            app.ToleranceSlider = uislider(app.RegionGrowingPanel);
            app.ToleranceSlider.Limits = [0 40];
            app.ToleranceSlider.Value = 3;
            app.MorphologyPanel = uipanel(app.UIFigure);
            app.MorphologyPanel.Title = 'Morphology';
            app.MorphButton = uibutton(app.MorphologyPanel, 'push');
            app.MorphButton.ButtonPushedFcn = createCallbackFcn(app, @MorphButtonPushed, true);
            app.MorphButton.Text = 'Morph';
            app.ConnectedComponentLabelingPanel = uipanel(app.UIFigure);
            app.ConnectedComponentLabelingPanel.Title = 'Connected Component Labeling';
            app.CCLButton = uibutton(app.ConnectedComponentLabelingPanel, 'push');
            app.CCLButton.ButtonPushedFcn = createCallbackFcn(app, @CCLButtonPushed, true);
            app.CCLButton.Text = 'CCL';
            app.EdgeDetectionPanel = uipanel(app.UIFigure);
            app.EdgeDetectionPanel.Enable = 'off';
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.EdgeDetectionPanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskGeneration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end