classdef MaskGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        EdgeDetectionPanel       matlab.ui.container.Panel
        ConnectedComponentLabelingPanel  matlab.ui.container.Panel
        CCLButton                matlab.ui.control.Button
        MorphologyPanel          matlab.ui.container.Panel
        MorphButton              matlab.ui.control.Button
        RegionGrowingPanel       matlab.ui.container.Panel
        HeuristicSliderLabel_2   matlab.ui.control.Label
        HeuristicSlider          matlab.ui.control.Slider
        HeuristicSliderLabel     matlab.ui.control.Label
        UndoButton               matlab.ui.control.Button
        ToleranceSlider          matlab.ui.control.Slider
        ToleranceSliderLabel     matlab.ui.control.Label
        GrowNewRegionButton      matlab.ui.control.Button
        OtsuThresholdPanel       matlab.ui.container.Panel
        ManualThresholdPanel     matlab.ui.container.Panel
        FlipMaskButton           matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        ManualThresholdLabel     matlab.ui.control.Label
        Step3GenerateMasksLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ImageCounterLabel        matlab.ui.control.Label
        NextButton               matlab.ui.control.Button
        PrevButton               matlab.ui.control.Button
        NextStepButton           matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        SaveMaskButton           matlab.ui.control.Button
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        OriginalImageLabel       matlab.ui.control.Label
        MaskedImageLabel         matlab.ui.control.Label
        CompositeImageLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        name;
        coordinates;
        band;
        location; %char. The location of the sattelite images
        pixelSize; %char. The pixel size of the satellite images
        method; %char. The method of segmentation used
        images; % cell.
        imagesCount; % uint8
        displayedImageIndex = 1; % double. The index of the currently displayed image
        flippedMask = 0; % double. A boolean for flipping the mask colors.
        masks; %cell. A storage matrix of all masks of all images
        lastMasks; 
        
    end
    
    methods (Access = private)

        %Written by: Sergej Keser 11727255
        %Distance transform on a 1 dimensional structure
        function [output] = DT_1D(input, pass)
            INF=1000000000000000000000000000000000;

            [rows, columns]=size(input);
            v = zeros(rows, columns);
            output = zeros(rows, columns);
            modedInput=input.*pow2(32);

            if rows==1
                z = zeros(rows, columns+1);
                n=columns;
            else
                z = zeros(rows+1, columns);
                n=rows;
            end

            k=1;
            v(1) = 1;
            z(1) = -INF; %negative infinity
            z(2) = INF; %positive infinty


            for q =2:n
                while true
                    s = (((modedInput(q) + q * q) - (modedInput(v(k)) + v(k) * v(k))) / (2 * q - 2 * v(k)));

                    if s<=z(k)
                        k=k-1;
                    else
                        break;
                    end
                end
                k=k+1;
                v(k) = q;
                z(k) = s;
                z(k+1) = +INF;
            end

            k = 0;

            for q=1:n
                while z(k+1)<q
                    k=k+1;
                end
                value=((q-v(k))*(q-v(k)))+modedInput(v(k));

                if pass==1
                    output(q)=value;
                end

                if pass==2
                    if input(q)>value &&input(q)>1
                        output(q)=value;
                    else
                        output(q)=input(q);
                    end
                end
            end

        end

        %Written by: Sergej Keser e11727255
        %Preforms distance transform on a matrix (2D array) by transforming it
        %first allong columns, than allong rows, and than by checking the
        %surrounding diagonal pixels
        function [output2D] = DT_2F(input2D)
        
        [rows, columns]=size(input2D);
        output2D=zeros(rows, columns);
        
        width=columns;
        height=rows;
        
        %transforming along columns
        for i=1:width
            output2D(:, i)=DT_1D(input2D(:,i), 1);
        end
        
        %transforming along rows
        for i=1:height
            output2D(i,:)=DT_1D(output2D(i,:), 2);
        end
        %checking the diagonal pixels
        for x=1:height
            for y=1:width
                if output2D(x,y)<4
                    continue;
                else
                    radius=sqrt(output2D(x,y))-1;
                    radius=round(radius);
                    for i=max([x-radius, 1]):min([height, x+radius])
                        for j=max([y-radius, 1]):min([width, y+radius])
                            if output2D(i,j)==0
        
                                dx=max(x-i, i-x);
                                dy=max(y-j, j-y);
                                distance=sqrt((dx*dx)+(dy*dy));
                                distance=distance*distance;
        
                                if output2D(x,y)>distance
                                    output2D(x,y)=distance;
                                else
                                    continue;
                                end
                            end
                        end
                    end
                end
            end
        end
        end

        %Author: Sergej Keser 11727255
        %img is a pre-processsed image (i.e. with dilation, erosion and region growig)
        %label represents a mask with N conected components
        %x,y is an optional input used for calls in GUI
        
        function [maxDistance, dAtCords, riverSize, distances] = distance_transform(app,label, N, x,y)
        
        if ~exist("x","var")
             %if the parameter does not exist, we default it to something
              x = 1;
        end
        if ~exist("y","var")
             %if the parameter does not exist, we default it to something
              y = 1;
        end
        %finding the biggest region, i.e. the region that represent the main
        %river/body of water
            maxRegion=[];
            %Finding the sizes (pixel counts) of all regions and puting them in a
            %single array
            for n=1:N
              [r, c] = find(label==n);
             rc=[r c];
             maxRegion=cat(1, maxRegion, size(rc,1));
            end
            
            %Using the max() function to find the biggest region
            riverSize = max(maxRegion(:));
            
            for labelIndex=1:N
                if(maxRegion(labelIndex)==riverSize)
                  break;
                end
            end
        
        %component representing the river as a binary image, 
        river=~(label==labelIndex);
        % disp(river);
        % imshow(river);
        imshow(river);
        
        %Apply distance transform
        distances=DT_2F(imcomplement(river));
        %figure, imshow(uint8(distances));
        
        %get the bigest distance to the edge for some pixel
        maxDistance=max(max(distances));

        %getting the distance to the nearest edge for the user-selected coordinates
        %temp=bwdist(river);
        dAtCords=distances(y,x);
        dAtCords=sqrt(dAtCords);
        
        end

        %Written by: Sergej Keser 11727255
        %Finds the appropriate coordinates of river banks where the river
        %is the widest
        %maxDistance is the biggest distance for some pixel
        %distances is a matrix result of distance transform
        %binImg is a binary image

        function [point1X,point1Y,point2X,point2Y] = widthAtPoint(app,maxDistance, distances, bwImg)
        
        %getting the coordinates of the point with maxDistance
        [xMax,yMax] = find(distances==maxDistance);
        xMax=xMax(1);
        yMax=yMax(1);
        
        %finding the point with this distance from the above defined point
        radius=sqrt(maxDistance)-1;
        radius=round(radius);
        [rows, columns]=size(distances);
        width=columns;
        height=rows;

        broken=0;
        for i=max([xMax-radius, 1]):min([height, xMax+radius])
            for j=max([yMax-radius, 1]):min([width, yMax+radius])
                if distances(i,j)==0
        
                    dx=max(xMax-i, i-xMax);
                    dy=max(yMax-j, j-yMax);
                    distance=sqrt((dx*dx)+(dy*dy));
                    distance=distance*distance;
        
                    if maxDistance==distance
                        broken=1;
                        break
                    else
                        continue;
                    end
                end
            end
            if broken==1
                break
            end
        end
        point1X=i;
        point1Y=j;
        
        dx=max(xMax-point1X,point1X-xMax);
        dy=max(yMax-point1Y, point1Y-yMax);
        m=dy/dx;
        
        point2X=xMax(1);
        point2Y=yMax(1);
        %deciding in which direction to search for the other river bank
        if xMax-point1X<0 && yMax-point1Y<0
            for k=1:xMax-1
                point2X=xMax-k;
                point2Y=round(yMax-k*m);
                if bwImg(point2X, point2Y)==0
                    break
                end
            end
        
        elseif xMax-point1X>0 && yMax-point1Y<0
            for k=1:height-xMax
                point2X=xMax+k;
                point2Y=round(yMax-k*m);
                if bwImg(point2X, point2Y)==0
                    break
                end
            end
        
        elseif xMax-point1X<0 && yMax-point1Y>0
            for k=1:xMax-1
                point2X=xMax-k;
                point2Y=round(yMax+k*m);
                if bwImg(point2X, point2Y)==0
                    break
                end
            end
        
        else
        
            for k=1:height-xMax
                point2X=xMax+k;
                point2Y=round(yMax+k*m);
                if bwImg(point2X, point2Y)==0
                    break
                end
            end
        
        
        end
        
        end
        
        function manualThresholding(app,imageIndex,threshold)
            
            temp_OriginalImage = app.images{1,imageIndex}; % load the original image from the data table
            temp_maskedImage = rgb2gray(temp_OriginalImage) >= threshold; % compute a binary mask (>=)
            
            if app.flippedMask == 1
                temp_maskedImage = ~temp_maskedImage;
            end
            
            app.masks{imageIndex} = temp_maskedImage; % we save the mask on the masks cell
        end
        
        function otsu(app,imageIndex)
            
            temp_OriginalImage = app.images{1,imageIndex}; % load the original image from the data table
            temp_MaskedImage = rgb2gray(temp_OriginalImage); % compute a binary mask (>=)
            
            top = 256;
            maximumInterClassVariance = 0.0;
            
            for i = 1:top
                foreGroundPixels = temp_MaskedImage >= i;
                backGroundPixels = ~foreGroundPixels;
                
                numberOfPixelsInForeground = sum(foreGroundPixels(:) == 1);
                meanIntensityOfForeground = mean(temp_MaskedImage(temp_MaskedImage >= i));
                
                numberOfPixelsInBackground = sum(backGroundPixels(:) == 1);
                meanIntensityOfBackground = mean(temp_MaskedImage(temp_MaskedImage < i));
                
                interClassVariance = numberOfPixelsInBackground * numberOfPixelsInForeground * (meanIntensityOfBackground - meanIntensityOfForeground)^2;
                
                if (interClassVariance >= maximumInterClassVariance)
                    level = i;
                    maximumInterClassVariance = interClassVariance;
                end
            end
            
            temp_MaskedImage = rgb2gray(temp_OriginalImage) <= level; % compute a binary mask
            app.masks{imageIndex} = temp_MaskedImage; % we save the mask on the masks cell
            
        end

        function connectedComponentLabeling(app)
            
        end


%Written by Maximilian Payer, 12023998
%A function the procedurally creats a mask based on a starting position of
%choice and the color values of adjacent pixels
function regionGrowing(app,imageIndex,similarity)
    
    image = app.images{1,imageIndex}; % load the original image from the data table
    mask = app.masks{1,imageIndex}; % compute a binary mask (>=)
    app.lastMasks{1,imageIndex} = mask;
    
    if(mask == 0)  % if mask is the scalar value 0, it creates an empty mask
        mask = im2gray(zeros(size(image)));
    end
    
    imshow(im2uint8(mask) + image); % show the combined image

    % try seed = ginput(1) % get the location of one mouse click
    try seed = ginput(1); % wait for one mouse click
    catch 
    end

    colorValue = image(uint16(seed(1, 2)), uint16(seed(1, 1)), :);

    result = or(mask, recursiveSeedGrowing(app, image, similarity, seed, mask, colorValue)); 
    %add newly created mask to the existing mask

    imshow(im2uint8(cat(3,result,result,result)) + image); % show the combined image 
    %(called twice, so that the changes are visible immediately)
    
    
    temp_MaskedImage = result;
    
    app.masks{imageIndex} = temp_MaskedImage; % save the mask so we can keep adding information to it
    
end

%Written by Maximilian Payer, 12023998
%A recursive function that grows a region, given a starting seed
function [temp_maskedImage] = recursiveSeedGrowing(app, image, similarity, seed, mask, colorValue)

    seedY = uint16(seed(1, 1));  %acquire the positions of the current Pixel
    seedX = uint16(seed(1, 2));  %acquire the positions of the current Pixel
    %it is to be noted that the horizontal values of the image
    %matrix are actually the second dimension, while the vertical
    %values are the first dimension. Hence, seedX stands for vertical
    %values, and vice versa

    temp_maskedImage = mask; 

    if(abs(sum(image(seedX, seedY, :) - colorValue)) > similarity*2)
        return;
    end

    temp_maskedImage = regionGrowingChunkHeuristic(app, image, similarity, seed, temp_maskedImage, 1, colorValue);
    temp_maskedImage(seedX, seedY) = 1; %add the current pixel to the mask


    %the following code block is only executed if the current pixel
    %is not on the very edge of the image (since checking the
    %neighbors would go out of bounds)
    if(seedX > 1 && seedY > 1 && seedX < size(image, 1) && seedY < size(image, 2))
        if(temp_maskedImage(seedX+1, seedY) == 0 && ... %check the pixel above
                sum(image(seedX+1, seedY, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX+1, seedY, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 2) = newSeed(1, 2) +1; 
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue); %recursive call on shifted seed
        end
        if(temp_maskedImage(seedX-1, seedY, :) == 0 && ... %check the pixel below
                sum(image(seedX-1, seedY, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX-1, seedY, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 2) = newSeed(1, 2) -1;
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue); %recursive call on shifted seed
        end
        if(temp_maskedImage(seedX, seedY+1, :) == 0 && ... %check the pixel to the right
                sum(image(seedX, seedY+1, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX, seedY+1, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 1) = newSeed(1, 1) +1;
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue);  %recursive call on shifted seed
        end
        if(temp_maskedImage(seedX, seedY-1, :) == 0 && ... %check the pixel to the left
                sum(image(seedX, seedY-1, :) - colorValue) <= similarity*2 && ...
                sum(image(seedX, seedY-1, :) - colorValue) >= -similarity*2) %filter by color, using the similarity value
            newSeed = seed;
            newSeed(1, 1) = newSeed(1, 1) -1;
            temp_maskedImage = recursiveSeedGrowing(app, image, similarity, newSeed, temp_maskedImage, colorValue); %recursive call on shifted seed 
        end
        

    end

end

%Written by Maximilian Payer, 12023998
%A recursive auxiliary function that aids the region growing function and
%increases its performance
%This function might include unwanted "islands" into the mask, but those
%are generally disposed of by the connected component labeling function
function [mask] = regionGrowingChunkHeuristic(app, image, similarity, seed, mask, depth, colorValue)
    
    seedY = uint16(seed(1, 1));  %acquire the position of the current Pixel
    seedX = uint16(seed(1, 2));  %acquire the position of the current Pixel

    if(mask(seedX, seedY) == 1)
        return
    end

    maxdepth = floor(min(min(min(size(image, 1) - seedX, size(image, 2) - seedY), seedX), seedY)/ 2) -2;
    %The maximum size that the heuristic can use for the given seed (based
    %on the image borders)


    if(depth > maxdepth)
        return;
    end

    newSeed = seed;

   %A rough heuristic check whether the square region of the given size (depth)
   %can be added without obstruction. If not, then a thresholding check
   %adds the appropriate pixels in a 13x13 region
   heuristicSize = app.HeuristicSlider.Value;
   if((abs(sum(image(seedX + depth*2, seedY, :)  - colorValue)) <= similarity*2 && ...
   abs(sum(image(seedX, seedY + depth*2, :)  - colorValue)) <= similarity*2  && ...
   abs(sum(image(seedX - depth*2, seedY, :)  - colorValue)) <= similarity*2  && ...
   abs(sum(image(seedX, seedY - depth*2, :)  - colorValue)) <= similarity*2  ))

      mask = regionGrowingChunkHeuristic(app, image, similarity, seed, mask, depth + 1, colorValue);
      

   elseif (depth <= 3 && maxdepth > heuristicSize)
        heuristicSize = app.HeuristicSlider.Value;
        mask(seedX- heuristicSize*2:seedX+heuristicSize*2, seedY-heuristicSize*2:seedY+heuristicSize*2) = ...
        sum(image(seedX-heuristicSize*2:seedX+heuristicSize*2, seedY-heuristicSize*2:seedY+heuristicSize*2) - colorValue, 3) <= similarity*2;

           for i = 1:heuristicSize*2
               newSeed(1,2) = seedX - heuristicSize*2 + i*2;
               newSeed(1,1) = seedY - heuristicSize*2 - 1;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
               
               newSeed(1,2) = seedX - heuristicSize*2 - 1;
               newSeed(1,1) = seedY - heuristicSize*2  + i*2;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
    
               newSeed(1,2) = seedX + heuristicSize*2 - i*2;
               newSeed(1,1) = seedY + heuristicSize*2 + 1;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
    
               newSeed(1,2) = seedX + heuristicSize*2 + 1;
               newSeed(1,1) = seedY + heuristicSize*2 - i*2;
               if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
                    mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
               end
           end
   
           return;
       else 
           return;
   end


   %A precise heuristic check whether the square region of the given size (depth)
   %can be added without obstruction

   for i = 1:depth
       if(abs(sum(image(seedX - depth*2 + i*4, seedY - depth*2, :)  - colorValue)) <= similarity*2 && ...
        abs(sum(image(seedX - depth*2, seedY - depth*2  + i*4, :)  - colorValue)) <= similarity*2  && ...
        abs(sum(image(seedX + depth*2 - i*4, seedY + depth*2, :)  - colorValue)) <= similarity*2  && ...
        abs(sum(image(seedX + depth*2, seedY + depth*2 - i*4, :)  - colorValue)) <= similarity*2  )
        else
           return;
        end
   end

   %functions for testing purposes
   %pause(0.2);
   %mask(1:size(image, 1), 1:size(image, 2)) = 1;
   %imshow(im2uint8(mask) + image);
    
   %Adds the square region to the mask
   mask(seedX-depth*2:seedX+depth*2, seedY-depth*2:seedY+depth*2) = 1;


   %Spreads the region growing algorithm to adjacent pixels of the square region
   for i = 1:depth*2
       newSeed(1,2) = seedX - depth*2 + i*2;
       newSeed(1,1) = seedY - depth*2 - 1;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end
       
       newSeed(1,2) = seedX - depth*2 - 1;
       newSeed(1,1) = seedY - depth*2 + i*2;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end

       newSeed(1,2) = seedX + depth*2 - i*2;
       newSeed(1,1) = seedY + depth*2 + 1;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end

       newSeed(1,2) = seedX + depth*2 + 1;
       newSeed(1,1) = seedY + depth*2 - i*2;
       if(mask(newSeed(1,2), newSeed(1,1)) ~= 1)
            mask = recursiveSeedGrowing(app, image, similarity, newSeed, mask, colorValue);
       end

   end


end
        
        function morphologicalOperation(app)
            
        end
        
        function generateDefaultMasks(app)
            % generate a default mask for each image
            
            if app.method == "Manual Thresholding"
                for (i = 1:app.imagesCount)
                    manualThresholding(app,i,app.Slider.Value);
                end
            elseif app.method == "Otsu's Thresholding" % check if we are on the otsu's thresholding mode
                for (i = 1:app.imagesCount)
                    otsu(app,i);
                end
            elseif app.method == "Region Growing"
                for (i = 1:app.imagesCount)
                    temp_OriginalImage = app.images{1,i}; % load the original image from the data table
                    temp_maskedImage = rgb2gray(temp_OriginalImage); % compute a binary mask (>=)
                    temp_maskedImage(:) = 0; % set the masking start point to a black image of the same size as the orignal image
                    app.masks{i} = temp_maskedImage; % we save the mask on the masks cell
                end
            end
            
        end
        
        function updateDisplayedImages(app) % updates the displayed images
            
            originalImage = app.images{1,app.displayedImageIndex}; % load the original image from the data table
            maskedImage = app.masks{app.displayedImageIndex}; % Load a mask from the masks cell if it already exists
            combinedImage = im2uint8(cat(3,maskedImage,maskedImage,maskedImage)) + originalImage; % create a composite image of the original image plus the image's mask
            
            app.ImageCounterLabel.Text = strcat("Image ", num2str(app.displayedImageIndex), " of ", num2str(app.imagesCount)); % set the displayed image text
            app.Image.ImageSource = originalImage; % set the imagesource1 to the original image
            app.Image2.ImageSource = im2uint8(cat(3,maskedImage,maskedImage,maskedImage)); % set the imagesource2 to the masked image (the grayscale mask has to be temporarily converted to a RGB image in order to be displayed in the image component)
            app.Image3.ImageSource = combinedImage; % set the imagesource3 to the combined image
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, location, coordinates, pixelSize, band, method, imageData, images, imagesCount)
            app.name = name;
            app.location = location; % store the location value
            app.coordinates = coordinates;
            app.pixelSize = pixelSize; % store the pixel size value
            app.band = band;
            app.method = method; % store the method value
            app.images = images;
            app.lastMasks = cell(1,1024);
            app.imagesCount = imagesCount;
            app.UITable.Data = imageData; % copy the imagedata into a new table
            
            if (strcmp(method,"Manual Thresholding")) % Turn on the UI components exclusive to the Manual Thresholding method
                app.ManualThresholdPanel.Visible = true;
            elseif (strcmp(method,"Otsu's Thresholding")) % Turn on the UI components exclusive to the Otsu's Thresholding method
                app.OtsuThresholdPanel.Visible = true;
            elseif (strcmp(method,"Region Growing")) % Turn on the UI components exclusive to the Region Growing method
                app.RegionGrowingPanel.Visible = true;
            elseif (strcmp(method,"Edge Detection")) % Turn on the UI components exclusive to the Region Growing method
                app.RegionGrowingPanel.Visible = true;
            end
            app.masks = cell(1,imagesCount); % create a new storage matrix for all masks of all pictures, this is initially empty.
            
            generateDefaultMasks(app);
            updateDisplayedImages(app); % update the displayed images
            
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            selectedDirectory = uigetdir(); % get a directory from user where they want to store the mask
            if (selectedDirectory == 0) % Make sure user didn't cancel uigetdir dialog
                return;
            end
            
            originalImage = app.images{1,app.displayedImageIndex}; % load the original image from the data table
            maskedImage = app.masks{app.displayedImageIndex}; % Load a mask from the masks cell if it already exists
            combinedImage = im2uint8(cat(3,maskedImage,maskedImage,maskedImage)) + originalImage;
   
            timestamp = num2str(posixtime(datetime)); 
            
            imwrite(maskedImage,strcat(selectedDirectory,"\",timestamp,"_",num2str(app.displayedImageIndex),"_mask.png")); % store an image of the mask
            imwrite(combinedImage,strcat(selectedDirectory,"\",timestamp,"_",num2str(app.displayedImageIndex),"_mask_combined.png")); % store an image of the composite
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.displayedImageIndex < size(app.UITable.Data,1) % if there is a next row in the table
                app.displayedImageIndex = app.displayedImageIndex + 1; % increase the index of the displayed row
                updateDisplayedImages(app); % update the displayed images
            end

        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if app.displayedImageIndex > 1 % if there is a previous row in the table
                app.displayedImageIndex = app.displayedImageIndex - 1; % decrease the index of the displayed row
                updateDisplayedImages(app); % update the displayed images
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            manualThresholding(app,app.displayedImageIndex,app.Slider.Value);
            updateDisplayedImages(app); % update the displyed images (with a new manual thresholding slider value)
        end

        % Button pushed function: FlipMaskButton
        function FlipMaskButtonPushed(app, event)
            if app.flippedMask == 0 % bitflip the flipped mask boolean
                app.flippedMask = 1;
            else
                app.flippedMask = 0;
            end
            
            manualThresholding(app,app.displayedImageIndex,app.Slider.Value);
            updateDisplayedImages(app); % update the displayed images

        end

        % Button pushed function: GrowNewRegionButton
        function GrowNewRegionButtonPushed(app, event)
            % Do region growing
           
           regionGrowing(app,app.displayedImageIndex,app.ToleranceSlider.Value);
           app.updateDisplayedImages(); % update th displayed images
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            DataPlotting(app.location,app.pixelSize,app.method,app.UITable.Data,app.images,app.imagesCount,app.masks);
            app.delete;
        end

        % Button pushed function: CCLButton
        function CCLButtonPushed(app, event)
            
        end

        % Button pushed function: MorphButton
        function MorphButtonPushed(app, event)
            maskedImage = app.masks{app.displayedImageIndex}; % Load a mask from the masks cell if it already exists            
            %do morphology
            app.masks{app.displayedImageIndex} = maskedImage;
            updateDisplayedImages(app); % update the displayed images
        end

        % Button pushed function: UndoButton
        function undoRegionGrwoing(app, event)
            if(size(app.lastMasks{1, app.displayedImageIndex}, 1) ~= 0)
                app.masks{1, app.displayedImageIndex} = app.lastMasks{1, app.displayedImageIndex};
                imshow(im2uint8(app.masks{1, app.displayedImageIndex}) + app.images{1, app.displayedImageIndex}); % show the combined image
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.CompositeImageLabel = uilabel(app.UIFigure);
            app.CompositeImageLabel.Text = 'Composite Image';
            app.MaskedImageLabel = uilabel(app.UIFigure);
            app.MaskedImageLabel.Text = 'Masked Image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.Image3 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.SaveMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Year'; 'Size'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false true false false];
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = '<- Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next ->';
            app.ImageCounterLabel = uilabel(app.UIFigure);
            app.ImageCounterLabel.Text = 'Image 0 of 0';
            app.Image = uiimage(app.UIFigure);
            app.Step3GenerateMasksLabel = uilabel(app.UIFigure);
            app.Step3GenerateMasksLabel.Text = 'Step 3: Generate Masks';
            app.ManualThresholdPanel = uipanel(app.UIFigure);
            app.ManualThresholdPanel.Visible = 'off';
            app.ManualThresholdLabel = uilabel(app.ManualThresholdPanel);
            app.ManualThresholdLabel.Text = 'Manual Threshold';
            app.Slider = uislider(app.ManualThresholdPanel);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.FlipMaskButton = uibutton(app.ManualThresholdPanel, 'push');
            app.FlipMaskButton.ButtonPushedFcn = createCallbackFcn(app, @FlipMaskButtonPushed, true);
            app.FlipMaskButton.Text = 'Flip Mask';
            app.OtsuThresholdPanel = uipanel(app.UIFigure);
            app.OtsuThresholdPanel.Visible = 'off';
            app.RegionGrowingPanel = uipanel(app.UIFigure);
            app.RegionGrowingPanel.Visible = 'off';
            app.GrowNewRegionButton = uibutton(app.RegionGrowingPanel, 'push');
            app.GrowNewRegionButton.ButtonPushedFcn = createCallbackFcn(app, @GrowNewRegionButtonPushed, true);
            app.GrowNewRegionButton.Text = 'Grow New Region';
            app.ToleranceSliderLabel = uilabel(app.RegionGrowingPanel);
            app.ToleranceSliderLabel.Text = 'Tolerance';
            app.ToleranceSlider = uislider(app.RegionGrowingPanel);
            app.ToleranceSlider.Limits = [0 40];
            app.ToleranceSlider.Value = 3;
            app.UndoButton = uibutton(app.RegionGrowingPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @undoRegionGrwoing, true);
            app.UndoButton.Text = 'Undo';
            app.HeuristicSliderLabel = uilabel(app.RegionGrowingPanel);
            app.HeuristicSliderLabel.Text = 'Heuristic ';
            app.HeuristicSlider = uislider(app.RegionGrowingPanel);
            app.HeuristicSlider.Limits = [0 25];
            app.HeuristicSlider.Value = 4;
            app.HeuristicSliderLabel_2 = uilabel(app.RegionGrowingPanel);
            app.HeuristicSliderLabel_2.Text = 'Size';
            app.MorphologyPanel = uipanel(app.UIFigure);
            app.MorphologyPanel.Title = 'Morphology';
            app.MorphButton = uibutton(app.MorphologyPanel, 'push');
            app.MorphButton.ButtonPushedFcn = createCallbackFcn(app, @MorphButtonPushed, true);
            app.MorphButton.Text = 'Morph';
            app.ConnectedComponentLabelingPanel = uipanel(app.UIFigure);
            app.ConnectedComponentLabelingPanel.Title = 'Connected Component Labeling';
            app.CCLButton = uibutton(app.ConnectedComponentLabelingPanel, 'push');
            app.CCLButton.ButtonPushedFcn = createCallbackFcn(app, @CCLButtonPushed, true);
            app.CCLButton.Text = 'CCL';
            app.EdgeDetectionPanel = uipanel(app.UIFigure);
            app.EdgeDetectionPanel.Enable = 'off';
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.EdgeDetectionPanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskGeneration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end